classdef map_it < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        mapitUIFigure           matlab.ui.Figure
        wallsCheckBox           matlab.ui.control.CheckBox
        rivertypeDropDownLabel  matlab.ui.control.Label
        rivertypeDropDown       matlab.ui.control.DropDown
        sizenSliderLabel        matlab.ui.control.Label
        sizenSlider             matlab.ui.control.Slider
        MapnxmLabel             matlab.ui.control.Label
        sizemSliderLabel        matlab.ui.control.Label
        sizemSlider             matlab.ui.control.Slider
        GenerateButton          matlab.ui.control.Button
        SaveButton              matlab.ui.control.Button
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            n=app.sizenSlider.Value;
            n=fix(n/1);
            m=app.sizemSlider.Value;
            m=fix(m/1);
            app.MapnxmLabel.Text=['Map ' int2str(n) 'x' int2str(m)] ;
            wall=app.wallsCheckBox.Value; %стены
            town=app.rivertypeDropDown.Value;
            switch town
        
            case 'spb'
                town=3;
            case 'without river'
                town=1;
            case 'moscow'
                town=2;
            case 'venice' 
                town=4;
            end
            global M;
            M=fmap_app(n,m,town,wall);
            figure(1)
            imshow(M)
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            n=app.sizenSlider.Value;
            n=fix(n/1);
            m=app.sizemSlider.Value;
            m=fix(m/1);
            app.MapnxmLabel.Text=['Map ' int2str(n) 'x' int2str(m)] ;
            wall=app.wallsCheckBox.Value; %стены
            town=app.rivertypeDropDown.Value;
            switch town
        
            case 'spb'
                town=3;
            case 'without river'
                town=1;
            case 'moscow'
                town=2;
            case 'venice' 
                town=4;
            end
            global M;
            M=fmap_app(n,m,town,wall);
            figure(1)
            imshow(M)


        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            n=app.sizenSlider.Value;
            n=fix(n/1);
            m=app.sizemSlider.Value;
            m=fix(m/1);
        [file,path]=uiputfile('*.jpg','Save',['_map_' num2str(n) 'x' num2str(m) '.jpg']);
        X=[path file];    
           % X=['exampls\' num2str(counter) '_map_' num2str(n) 'x' num2str(m) '.jpg'];
        if file
            global M;  
            imwrite(M, X); 
        end
        end

        % Value changed function: sizenSlider
        function sizenSliderValueChanged(app, event)
            value = app.sizenSlider.Value;
            n=app.sizenSlider.Value;
            n=fix(n/1);
            m=app.sizemSlider.Value;
            m=fix(m/1);
            app.MapnxmLabel.Text=['Map ' int2str(n) 'x' int2str(m)] ;
            wall=app.wallsCheckBox.Value; %стены
            town=app.rivertypeDropDown.Value;
            switch town
        
            case 'spb'
                town=3;
            case 'without river'
                town=1;
            case 'moscow'
                town=2;
            case 'venice' 
                town=4;
            end
            global M;
            M=fmap_app(n,m,town,wall);
            figure(1)
            imshow(M)
        end

        % Value changed function: sizemSlider
        function sizemSliderValueChanged(app, event)
            value = app.sizemSlider.Value;
            n=app.sizenSlider.Value;
            n=fix(n/1);
            m=app.sizemSlider.Value;
            m=fix(m/1);
            app.MapnxmLabel.Text=['Map ' int2str(n) 'x' int2str(m)] ;
            wall=app.wallsCheckBox.Value; %стены
            town=app.rivertypeDropDown.Value;
            switch town
        
            case 'spb'
                town=3;
            case 'without river'
                town=1;
            case 'moscow'
                town=2;
            case 'venice' 
                town=4;
            end
            global M;
            M=fmap_app(n,m,town,wall);
            figure(1)
            imshow(M)
        end

        % Value changed function: wallsCheckBox
        function wallsCheckBoxValueChanged(app, event)
            value = app.wallsCheckBox.Value;
            n=app.sizenSlider.Value;
            n=fix(n/1);
            m=app.sizemSlider.Value;
            m=fix(m/1);
            app.MapnxmLabel.Text=['Map ' int2str(n) 'x' int2str(m)] ;
            wall=app.wallsCheckBox.Value; %стены
            town=app.rivertypeDropDown.Value;
            switch town
        
            case 'spb'
                town=3;
            case 'without river'
                town=1;
            case 'moscow'
                town=2;
            case 'venice' 
                town=4;
            end
            global M;
            M=fmap_app(n,m,town,wall);
            figure(1)
            imshow(M)
        end

        % Value changed function: rivertypeDropDown
        function rivertypeDropDownValueChanged(app, event)
            value = app.rivertypeDropDown.Value;
            n=app.sizenSlider.Value;
            n=fix(n/1);
            m=app.sizemSlider.Value;
            m=fix(m/1);
            app.MapnxmLabel.Text=['Map ' int2str(n) 'x' int2str(m)] ;
            wall=app.wallsCheckBox.Value; %стены
            town=app.rivertypeDropDown.Value;
        switch town
        
            case 'spb'
                town=3;
            case 'without river'
                town=1;
            case 'moscow'
                town=2;
            case 'venice' 
                town=4;
        end
            global M;
            M=fmap_app(n,m,town,wall);
            figure(1)
            imshow(M)
        end

        % Close request function: mapitUIFigure
        function mapitUIFigureCloseRequest(app, event)
            close('all')
            delete(app)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.mapitUIFigure = uifigure;
            app.mapitUIFigure.Name = 'map it';
            app.mapitUIFigure.CloseRequestFcn = createCallbackFcn(app, @mapitUIFigureCloseRequest, true);
            app.wallsCheckBox = uicheckbox(app.mapitUIFigure);
            app.wallsCheckBox.ValueChangedFcn = createCallbackFcn(app, @wallsCheckBoxValueChanged, true);
            app.wallsCheckBox.Text = 'walls';
            app.rivertypeDropDownLabel = uilabel(app.mapitUIFigure);
            app.rivertypeDropDownLabel.Text = 'river type';
            app.rivertypeDropDown = uidropdown(app.mapitUIFigure);
            app.rivertypeDropDown.Items = {'without river', 'moscow', 'spb', 'venice'};
            app.rivertypeDropDown.ValueChangedFcn = createCallbackFcn(app, @rivertypeDropDownValueChanged, true);
            app.rivertypeDropDown.Value = 'spb';
            app.sizenSliderLabel = uilabel(app.mapitUIFigure);
            app.sizenSliderLabel.Text = 'size n';
            app.sizenSlider = uislider(app.mapitUIFigure);
            app.sizenSlider.Limits = [1 16];
            app.sizenSlider.MajorTicks = [];
            app.sizenSlider.MajorTickLabels = {};
            app.sizenSlider.ValueChangedFcn = createCallbackFcn(app, @sizenSliderValueChanged, true);
            app.sizenSlider.MinorTicks = [];
            app.sizenSlider.Value = 5;
            app.MapnxmLabel = uilabel(app.mapitUIFigure);
            app.MapnxmLabel.Text = 'Map nxm';
            app.sizemSliderLabel = uilabel(app.mapitUIFigure);
            app.sizemSliderLabel.Text = 'size m';
            app.sizemSlider = uislider(app.mapitUIFigure);
            app.sizemSlider.Limits = [1 16];
            app.sizemSlider.MajorTicks = [];
            app.sizemSlider.MajorTickLabels = {};
            app.sizemSlider.ValueChangedFcn = createCallbackFcn(app, @sizemSliderValueChanged, true);
            app.sizemSlider.MinorTicks = [];
            app.sizemSlider.Value = 5;
            app.GenerateButton = uibutton(app.mapitUIFigure, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.SaveButton = uibutton(app.mapitUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
        end
    end

    methods (Access = public)

        % Construct app
        function app = map_it

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.mapitUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.mapitUIFigure)
        end
    end
end